classdef GUI_prostownik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIprostownikUIFigure   matlab.ui.Figure
        UAxes                   matlab.ui.control.UIAxes
        IAxes                   matlab.ui.control.UIAxes
        VoVLabel                matlab.ui.control.Label
        VoEditField             matlab.ui.control.NumericEditField
        fHzLabel                matlab.ui.control.Label
        fEditField              matlab.ui.control.NumericEditField
        NoEditFieldLabel        matlab.ui.control.Label
        NoEditField             matlab.ui.control.NumericEditField
        delta_tsEditFieldLabel  matlab.ui.control.Label
        delta_tEditField        matlab.ui.control.NumericEditField
        RYSUJButton             matlab.ui.control.Button
        ROmEditFieldLabel       matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        CFLabel                 matlab.ui.control.Label
        CEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        R = [];
        Vo = [];
        f = [];
        No = [];
        delta_t = [];
        C = [];  
        
        Vs = [];
        Vr = [];
        Ic = [];
        Ir = [];
        Id = [];
        t = [];
        t_a = [];
        idx_t_a = [];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UAxes.Toolbar = "off";
            %app.IAxes.Toolbar = "off";
            
        end

        % Button pushed function: RYSUJButton
        function RYSUJButtonPushed(app, event)
            app.RYSUJButton.Enable = "off"; 
            
            app.R = app.REditField.Value;
            app.C = app.CEditField.Value;
            app.Vo = app.VoEditField.Value;
            app.f = app.fEditField.Value;
            app.No = app.NoEditField.Value;
            app.delta_t = app.delta_tEditField.Value;
            
            if(app.R < 10) || (app.R > 10e7)   
                uialert(app.GUIprostownikUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
            elseif(app.C < 10e-9) || (app.C > 10e-3) 
                uialert(app.GUIprostownikUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
            else
                [app.Vs,app.Vr,app.Ic,app.Ir,app.Id,app.t,app.t_a] = prostownik(app.R,app.C,app.Vo,app.f,app.No,app.delta_t);
                [~,app.idx_t_a] = intersect(app.t,app.t_a);
                
                hold(app.UAxes,"on");
                plot(app.UAxes,app.t,app.Vs,'b--');
                plot(app.UAxes,app.t,app.Vr,'r');
                plot(app.UAxes,app.t_a,app.Vs(app.idx_t_a),'m*');
                plot(app.UAxes,app.t_a,app.Vr(app.idx_t_a),'m*');
                hold(app.UAxes,"off");
                legend(app.UAxes,{'Vs','Vr','t_a'},'Location','southeast');
    
                hold(app.IAxes,"on");
                plot(app.IAxes,app.t,app.Ir,'r');
                plot(app.IAxes,app.t,app.Ic,'k');
                %plot(app.IAxes,app.t,app.Id,'c');
                plot(app.IAxes,app.t_a,app.Ir(app.idx_t_a),'m*');
                plot(app.IAxes,app.t_a,app.Ic(app.idx_t_a),'m*');
                %plot(app.IAxes,app.t_a,app.Id(app.idx_t_a),'m*');
                %legend(app.IAxes,{'Ir','Ic','Id','t_a'},'Location','southeast');
                legend(app.IAxes,{'Ir','Ic','t_a'},'Location','southeast');
                hold(app.IAxes,"off");                           
            end
            
            app.RYSUJButton.Enable = "on"; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIprostownikUIFigure = uifigure('Visible', 'off');
            app.GUIprostownikUIFigure.Name = 'GUI prostownik';
            app.UAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.UAxes, 'Napiecie')
            xlabel(app.UAxes, 't [s]')
            ylabel(app.UAxes, 'U [V]')
            app.IAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.IAxes, 'Natezenie')
            xlabel(app.IAxes, 't [s]')
            ylabel(app.IAxes, 'I [A]')
            app.VoVLabel = uilabel(app.GUIprostownikUIFigure);
            app.VoVLabel.Text = 'Vo [V]';
            app.VoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.fHzLabel = uilabel(app.GUIprostownikUIFigure);
            app.fHzLabel.Text = 'f [Hz]';
            app.fEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.NoEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.NoEditFieldLabel.Text = 'No';
            app.NoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.delta_tsEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.delta_tsEditFieldLabel.Text = 'delta_t [s]';
            app.delta_tEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.RYSUJButton = uibutton(app.GUIprostownikUIFigure, 'push');
            app.RYSUJButton.ButtonPushedFcn = createCallbackFcn(app, @RYSUJButtonPushed, true);
            app.RYSUJButton.Text = 'RYSUJ';
            app.ROmEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.ROmEditFieldLabel.Text = 'R [Om]';
            app.REditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.CFLabel = uilabel(app.GUIprostownikUIFigure);
            app.CFLabel.Text = 'C [F]';
            app.CEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            % Show the figure after all components are created
            app.GUIprostownikUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_prostownik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIprostownikUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIprostownikUIFigure)
        end
    end
end